classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KlasyfikatorowocwUIFigure  matlab.ui.Figure
        RozpoznajowocButton        matlab.ui.control.Button
        WybierzzdjcieButton        matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imgOriginal
        neuralNetwork
        text % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WybierzzdjcieButton
        function WybierzzdjcieButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png'},'Select image to be displayed');
            if ~isequal(filename, 0) && ~isequal(filepath, 0)
                fullname = [filepath, filename];
                app.imgOriginal = imread(fullname);
                imshow(app.imgOriginal, 'Parent', app.UIAxes)
            end
            app.TextArea.Value = "";
        end

        % Button pushed function: RozpoznajowocButton
        function recogniseFruit(app, event)
            model = load('app_model.mat')
%                       
            image = app.imgOriginal;           
%             
            row = hog(image);
%             app.text = app.neuralNetwork.predictFnc(row);                                                    
%             display(app.text);
            % test wypisywania napisów
           
%             xt = [0.5 0.5];
%             app.UIAxes2.text(xt, app.text);
%             ax = app.UIAxes2;
%             ax.title('My Title','FontWeight','normal');
            app.TextArea.Value = model.app_model.predictFcn(row); 
            
        end

        % Callback function
        function showMessage(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KlasyfikatorowocwUIFigure = uifigure('Visible', 'off');
            app.KlasyfikatorowocwUIFigure.Name = 'Klasyfikator owoców';
            app.RozpoznajowocButton = uibutton(app.KlasyfikatorowocwUIFigure, 'push');
            app.RozpoznajowocButton.ButtonPushedFcn = createCallbackFcn(app, @recogniseFruit, true);
            app.RozpoznajowocButton.Text = 'Rozpoznaj owoc';
            app.WybierzzdjcieButton = uibutton(app.KlasyfikatorowocwUIFigure, 'push');
            app.WybierzzdjcieButton.ButtonPushedFcn = createCallbackFcn(app, @WybierzzdjcieButtonPushed, true);
            app.WybierzzdjcieButton.Text = 'Wybierz zdjęcie';
            app.TextArea = uitextarea(app.KlasyfikatorowocwUIFigure);
            app.UIAxes = uiaxes(app.KlasyfikatorowocwUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.KlasyfikatorowocwUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KlasyfikatorowocwUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KlasyfikatorowocwUIFigure)
        end
    end
end